classdef Fungal_T2M_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T2M                     matlab.ui.Figure
        PanelResults            matlab.ui.container.Panel
        PanelResults_2          matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        SettingsTab             matlab.ui.container.Tab
        PanelEnvironment        matlab.ui.container.Panel
        minLabel                matlab.ui.control.Label
        CNMin                   matlab.ui.control.NumericEditField
        maxLabel                matlab.ui.control.Label
        CNMax                   matlab.ui.control.NumericEditField
        TauMin                  matlab.ui.control.NumericEditField
        TauMax                  matlab.ui.control.NumericEditField
        minLabel_2              matlab.ui.control.Label
        NPMin                   matlab.ui.control.NumericEditField
        midLabel                matlab.ui.control.Label
        NPMid                   matlab.ui.control.NumericEditField
        maxLabel_2              matlab.ui.control.Label
        NPMax                   matlab.ui.control.NumericEditField
        KappaMin                matlab.ui.control.NumericEditField
        KappaMid                matlab.ui.control.NumericEditField
        KappaMax                matlab.ui.control.NumericEditField
        DeltaLabel              matlab.ui.control.Label
        Delta                   matlab.ui.control.NumericEditField
        NolevelsLabel           matlab.ui.control.Label
        Res1                    matlab.ui.control.NumericEditField
        CNLabel                 matlab.ui.control.Label
        TauhLabel               matlab.ui.control.Label
        NPLabel                 matlab.ui.control.Label
        KappaLabel              matlab.ui.control.Label
        DensityEditFieldLabel   matlab.ui.control.Label
        Density                 matlab.ui.control.NumericEditField
        PanelOrganism           matlab.ui.container.Panel
        CitotgmlLabel           matlab.ui.control.Label
        CinTotal                matlab.ui.control.NumericEditField
        NigmlLabel              matlab.ui.control.Label
        Nin                     matlab.ui.control.NumericEditField
        PigmlLabel              matlab.ui.control.Label
        Pin                     matlab.ui.control.NumericEditField
        AlphaLabel              matlab.ui.control.Label
        Alpha                   matlab.ui.control.NumericEditField
        BetaLabel               matlab.ui.control.Label
        Beta                    matlab.ui.control.NumericEditField
        EpsilonLabel            matlab.ui.control.Label
        Epsilon                 matlab.ui.control.NumericEditField
        LambdaLabel             matlab.ui.control.Label
        Lambda                  matlab.ui.control.NumericEditField
        RunModelButton          matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        fungalLabel             matlab.ui.control.Label
        motileLabel             matlab.ui.control.Label
        autolyticLabel          matlab.ui.control.Label
        CNPiRatio               matlab.ui.control.Label
        CNPiLabel               matlab.ui.control.Label
        CiLabel                 matlab.ui.control.Label
        Cin                     matlab.ui.control.NumericEditField
        CUELabel_2              matlab.ui.control.Label
        CUE                     matlab.ui.control.NumericEditField
        StrategycostsLabel      matlab.ui.control.Label
        DisplayPanel            matlab.ui.container.Panel
        DisplayLabel            matlab.ui.control.Label
        DisplayDropDown         matlab.ui.control.DropDown
        NPratioDropDownLabel    matlab.ui.control.Label
        NPratioDropDown         matlab.ui.control.DropDown
        OrganismDropDownLabel   matlab.ui.control.Label
        OrganismDropDown        matlab.ui.control.DropDown
        UpdateButton            matlab.ui.control.Button
        PrintModelButton        matlab.ui.control.Button
        SpecificResourcesPanel  matlab.ui.container.Panel
        Resource1DropDownLabel  matlab.ui.control.Label
        ResourceDropDown_1      matlab.ui.control.DropDown
        Resource2DropDownLabel  matlab.ui.control.Label
        ResourceDropDown_2      matlab.ui.control.DropDown
        Resource4DropDownLabel  matlab.ui.control.Label
        ResourceDropDown_4      matlab.ui.control.DropDown
        ResourceDropDown_3      matlab.ui.control.DropDown
        Resource3DropDownLabel  matlab.ui.control.Label
        RunResourceButton       matlab.ui.control.Button
        ResourceLabel_1         matlab.ui.control.Label
        TauhEditFieldLabel      matlab.ui.control.Label
        TauEditField_1          matlab.ui.control.NumericEditField
        ResourceLabel_2         matlab.ui.control.Label
        TauEditField_2          matlab.ui.control.NumericEditField
        ResourceLabel_3         matlab.ui.control.Label
        TauEditField_3          matlab.ui.control.NumericEditField
        ResourceLabel_4         matlab.ui.control.Label
        TauEditField_4          matlab.ui.control.NumericEditField
        ResourceLabel_CNP       matlab.ui.control.Label
        PrintResourcesButton    matlab.ui.control.Button
        ResourcesTab            matlab.ui.container.Tab
        ResourceTable           matlab.ui.control.Table
        ResourceAddButton       matlab.ui.control.Button
        ResourceRemoveButton    matlab.ui.control.Button
        ResourceUpdateButton    matlab.ui.control.Button
        ResourceLoadButton      matlab.ui.control.Button
        ResourceSaveButton      matlab.ui.control.Button
        ResourcePanelSave       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Mu % array of Mu results
        MuPlot % Mu plot data
        DPlot % digestion plot data
        Recalcitrance
        C_to_N_vector
        kappa_vector % Description
        ResourceTableIdx % row and column index for the selected cell in the resource table
    end
    
    methods (Access = private)
        
        function fncUpdateDisplayCYM(app)
            h = allchild(app.PanelResults);
            delete(h)
            N_to_P_limits = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappa_limits = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            t = tiledlayout(app.PanelResults,3,3,'TileSpacing',"compact");
            t.Title.String = 'N:P Ratio';
            t.YLabel.String = 'Total resource availability';
            for iKappa = 1:3
                for iN2P = 1:3
                    N_to_P = N_to_P_limits(iN2P);
                    kappa = kappa_limits(iKappa);
                    [CYM] = fnc_generate_CYM_merge(app, app.Mu.immobile{iKappa,iN2P}, app.Mu.motile{iKappa,iN2P}, app.Mu.autolytic{iKappa,iN2P},app.Mu.fungal{iKappa,iN2P});
                    ax = nexttile(t);
                    % Display image
                    imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector),CYM);
                    ax.XLabel.String = 'log_{10} Recalcitrance (h)';
                    if iN2P == 1
                        ax.YLabel.String = {['Digestion radius \kappa = ' num2str(kappa)], '','log_{10} C:N ratio'};
                    else
                        ax.YLabel.String = 'log C:N ratio';
                    end
                    ax.YDir = 'normal';
                    ax.PlotBoxAspectRatio = [1 1 1];
                    ax.Title.String = ['N:P = ' num2str(N_to_P)];
                    ax.Title.FontWeight = 'normal';
                end
            end
            drawnow
        end
        
        function fncUpdateDisplayGrowth(app)
            h = allchild(app.PanelResults);
            delete(h)
            map = colorcet('L3');
            N_to_P_limits = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappa_limits = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            app.PanelResults.AutoResizeChildren = 'off';
            switch app.NPratioDropDown.Value
                case 'min'
                    N_to_P = N_to_P_limits(1);
                    iN2P = 1;
                case 'mid'
                    N_to_P = N_to_P_limits(2);
                    iN2P = 2;
                case 'max'
                    N_to_P = N_to_P_limits(3);
                    iN2P = 3;
            end
            for iKappa = 1:3
                kappa = kappa_limits(iKappa);
                % normalse to the fastest growth
                immobile = app.Mu.immobile{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                autolytic = app.Mu.autolytic{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                motile = app.Mu.motile{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                fungi = app.Mu.fungal{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                % set colour code
                immobile_im = applycolourmap(immobile, map, [0, 1]);
                autolytic_im = applycolourmap(autolytic, map, [0, 1]);
                motile_im = applycolourmap(motile, map, [0, 1]);
                fungi_im = applycolourmap(fungi, map, [0, 1]);
                % Display immobile image
                ax = subplot(3,4,1+(iKappa-1).*4,'Parent',app.PanelResults);
                imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector), immobile_im)
                ax.YDir = 'normal';
                ax.PlotBoxAspectRatio = [1 1 1];
                ax.XLabel.String = 'log_{10} Recalcitrance (h)';
                if iKappa == 2
                    ax.YLabel.String = {'\fontsize{16} Total resource availability';''; ['Digestion radius \kappa = ' num2str(kappa)]; '';'log_{10} C:N ratio'};
                else
                    ax.YLabel.String = {['Digestion radius \kappa = ' num2str(kappa)], '','log_{10} C:N ratio'};
                end
                ax.Title.String = 'Immobile Growth Rate';
                % Display autolytic image
                ax = subplot(3,4,2+(iKappa-1).*4,'Parent',app.PanelResults);
                imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector), autolytic_im)
                ax.YDir = 'normal';
                ax.PlotBoxAspectRatio = [1 1 1];
                ax.XLabel.String = 'log_{10} Recalcitrance (h)';
                ax.YLabel.String = 'log_{10} C:N ratio';
                ax.Title.String = 'Autolytic Growth Rate';
                % Display motile image
                ax = subplot(3,4,3+(iKappa-1).*4,'Parent',app.PanelResults);
                imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector), motile_im)
                ax.YDir = 'normal';
                ax.PlotBoxAspectRatio = [1 1 1];
                ax.XLabel.String = 'log_{10} Recalcitrance (h)';
                ax.YLabel.String = 'log_{10} C:N ratio';
                ax.Title.String = 'Motile Growth Rate';
                % display fungal image
                ax = subplot(3,4,4+(iKappa-1).*4,'Parent',app.PanelResults);
                imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector), fungi_im)
                ax.YDir = 'normal';
                ax.PlotBoxAspectRatio = [1 1 1];
                ax.XLabel.String = 'log_{10} Recalcitrance (h)';
                ax.YLabel.String = 'log_{10} C:N ratio';
                ax.Title.String = 'Fungal Growth Rate';
            end
            cb = colorbar(ax,'southoutside');
            cb.Position = [0.45 0.0467 0.33 0.0121];
            cb.Label.Interpreter = 'latex';
            cb.Label.String = '$\frac{\mbox{Apparent growth rate}}{\mbox{Maximal specific growth rate}}$';
            cb.Label.Units = 'normalized';
            cb.Label.FontSize = 12;
            cb.Label.Position = [-0.5 1.5 0];
            colormap(ax,map)
            sgtitle(app.PanelResults,['N:P Ratio = ' num2str(N_to_P)])
            drawnow
        end
        
        function fncUpdateDisplayRatio(app)
            h = allchild(app.PanelResults);
            delete(h)
            % max_ratio is the ratio between growth rate results in a colour that is
            % indistinguishable from the colour given when the ratio between growth
            % rates is infinite
            max_ratio = 2;
            map = colorcet('R3');
            N_to_P_limits = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappa_limits = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            app.PanelResults.AutoResizeChildren = 'off';
            for iKappa = 1:3
                for iN2P = 1:3
                    N_to_P = N_to_P_limits(iN2P);
                    kappa = kappa_limits(iKappa);
                    mu1 = app.Mu.fungal{iKappa,iN2P};
                    switch app.OrganismDropDown.Value
                        case 'immobile'
                            mu2 = app.Mu.immobile{iKappa,iN2P};
                        case 'autolytic'
                            mu2 = app.Mu.autolytic{iKappa,iN2P};
                        case 'motile'
                            mu2 = app.Mu.motile{iKappa,iN2P};
                    end
                    log_ratio = log(mu1) - log(mu2);
                    % if mu1 or mu2 are zero
                    idx = find(mu1==0 | mu2==0);
                    % normalise the ratio to the maximum for display
                    Nratio = log_ratio/log(max_ratio);
                    growth_ratio = zeros(size(Nratio));
                    % fix the limits
                    Nratio(Nratio>1) = 1;
                    Nratio(Nratio<-1) = -1;
                    %                     growth_ratio(Nratio>0) = 2 + 2*(Nratio(Nratio>0)).^0.8;
                    %                     growth_ratio(Nratio<0) = 2 - 2*(-Nratio(Nratio<0)).^0.8;
                    growth_ratio(Nratio>0) = 2 + 2*(Nratio(Nratio>0));
                    growth_ratio(Nratio<0) = 2 - 2*(-Nratio(Nratio<0));
                    rgbim = applycolourmap(growth_ratio, map, [0, 4]);
                    % set any nan or inf to white
                    sz = numel(growth_ratio);
                    idxW = [idx,idx+sz,idx+sz*2];
                    rgbim(idxW) = 1;
                    ax = subplot(3,3,(iKappa-1)*3 + iN2P,'Parent',app.PanelResults);
                    % Display image
                    imagesc(ax,log(app.Recalcitrance)/log(10), log(app.C_to_N_vector)/log(10),rgbim);
                    ax.XLabel.String = 'log_{10} Recalcitrance (h)';
                    if iN2P == 1 && iKappa == 2
                        ax.YLabel.String = {'\fontsize{14} Total resource availability';'';['Digestion radius \kappa = ' num2str(kappa)]; '';'log_{10} C:N ratio'};
                    elseif iN2P == 1
                        ax.YLabel.String = {['Digestion radius \kappa = ' num2str(kappa)]; '';'log_{10} C:N ratio'};
                    else
                        ax.YLabel.String = 'log_{10} C:N ratio';
                    end
                    ax.YDir = 'normal';
                    ax.PlotBoxAspectRatio = [1 1 1];
                    ax.Title.String = ['N:P = ' num2str(N_to_P)];
                    ax.Title.FontWeight = 'normal';
                end
            end
            cb = colorbar(ax,'southoutside');
            cb.Position = [0.45 0.0467 0.33 0.0121];
            cb.Label.Interpreter = 'latex';
            cb.Label.String = '$\frac{\mbox{Hyphal growth rate}}{\mbox{Unicell growth rate}}$';
            cb.Label.Units = 'normalized';
            cb.Label.FontSize = 12;
            cb.Label.Position = [-0.5 1.5 0];
            cb.Limits = [0 1];
            cb.Ticks = [0.0000    0.2075    0.3685    0.5000    0.6315    0.7925    1.0000];
            cb.TickLabelInterpreter = 'Latex';
            cb.TickLabels = [ ...
                "$\frac{\mbox{10}}{\mbox{20}}$", ...
                "$\frac{\mbox{10}}{\mbox{15}}$", ...
                "$\frac{\mbox{10}}{\mbox{12}}$", ...
                "$\frac{\mbox{10}}{\mbox{10}}$", ...
                "$\frac{\mbox{12}}{\mbox{10}}$", ...
                "$\frac{\mbox{15}}{\mbox{10}}$", ...
                "$\frac{\mbox{20}}{\mbox{10}}$"];
            colormap(ax,map)
            sgtitle(app.PanelResults,['Growth Ratio - fungal to ' app.OrganismDropDown.Value])
        end
        
        function fncUpdateMuPlot(app)
            h = allchild(app.PanelResults_2);
            delete(h)
            t = tiledlayout(app.PanelResults_2,4,2,'TileSpacing',"normal");
            t.Title.String = 'Optimal growth and digestion rates';
            t.YLabel.String = 'Specific resource quality';
            for iR = 1:4
                if iR == 1
                    environment = app.ResourceDropDown_1.Value;
                elseif iR == 2
                    environment = app.ResourceDropDown_2.Value;
                elseif iR == 3
                    environment = app.ResourceDropDown_3.Value;
                else
                    environment = app.ResourceDropDown_4.Value;
                end
                ax1 = nexttile(t);
                hold(ax1,'off')
                plot(ax1,app.kappa_vector, app.MuPlot.motile{iR}*24, 'c', 'LineWidth', 1)
                hold(ax1,'on')
                plot(ax1,app.kappa_vector, app.MuPlot.autolytic{iR}*24, 'g', 'LineWidth', 1)
                plot(ax1,app.kappa_vector, app.MuPlot.fungal{iR}*24, 'm', 'LineWidth', 1)
                plot(ax1,app.kappa_vector, app.MuPlot.immobile{iR}*24, 'k--', 'LineWidth', 1)
                ax1.XLim = [1 8];
                ax1.XLabel.String = 'Digestion radius \kappa';
                ax1.YLabel.String = 'Apparent growth rate (d^{-1})';
                title(ax1,[environment ' - growth rate'])
                ax2 = nexttile(t);
                hold(ax2,'off')
                plot(ax2,app.kappa_vector, app.DPlot.motile{iR}*24, 'c', 'LineWidth', 1)
                hold(ax2,'on')
                plot(ax2,app.kappa_vector, app.DPlot.autolytic{iR}*24, 'g', 'LineWidth', 1)
                plot(ax2,app.kappa_vector, app.DPlot.fungal{iR}*24, 'm', 'LineWidth', 1)
                plot(ax2,app.kappa_vector, app.DPlot.immobile{iR}*24, 'k--', 'LineWidth', 1)
                ax2.XLim = [1 8];
                ax2.XLabel.String = 'Digestion radius \kappa';
                ax2.YLabel.String = 'Digestion Rate (g ml^{-1} d^{-1})';
                title(ax2,[environment ' - digestion rate'])
                if iR == 4
                    legend(ax2,'Motile', 'Autolytic', 'Fungi', 'Immobile','Orientation','Vertical','Box','off','Location','southoutside','NumColumns',2);
                end
            end
        end
        
        function cym = ColourLegend(app)
            % we want the sides of the triangle to be 256, so the arena need to be
            % larger than this - we use 300
            zoom = 3;
            sz = 300.*zoom;
            cym = zeros(sz,sz,3);
            %the center offset is half the size
            xoffset = 1.25*sz/2;
            yoffset = sz/2;
            % the radius needs to be:
            radius = zoom.*128./cos(pi/6);
            % get the vertices of the triangle
            for i = 1:3
                theta = pi - (i-1)*2*pi/3;
                [x(i), y(i)] = pol2cart(theta,radius);
                r(i) = round(x(i)+xoffset);
                c(i) = round(y(i)+yoffset);
            end
            % construct a mask
            mask = false(sz,sz);
            mask = roipoly(mask,c,r);
            %
            cym(r(1),c(1),2) = 1;
            cym(r(2),c(2),3) = 1;
            cym(r(3),c(3),1) = 1;
            % convert to distance transform
            cym(:,:,1) = bwdist(cym(:,:,1),'euclidean').*mask -128.*zoom;
            cym(:,:,2) = bwdist(cym(:,:,2),'euclidean').*mask -128.*zoom;
            cym(:,:,3) = bwdist(cym(:,:,3),'euclidean').*mask -128.*zoom;
            % truncate zero values
            cym(cym<0) = 0;
            % normalise
            cym = cym./max(cym(:));
            % set the background to white
            %cym(cym==0) = 1;
            background = repmat(~mask,1,1,3);
            cym(background) = 0.94;
%             % make a triangular mask
%             xCenter = 51;
%             yCenter = 51;
%             radius = 51;
%             theta = linspace(0, 2*pi, 4);
%             theta = theta - pi/2;
%             x = radius * cos(theta) + xCenter;
%             y = radius * sin(theta) + yCenter;
%             bw = poly2mask(x, y, 101,101);
%             % make a grayscale wedge
%             im = zeros(size(bw));
%             for i = 1:76
%                 im(i,:) = 1-(i-1)/60;
%             end
%             r = im;
%             g = imrotate(r,-120,'crop');
%             b = imrotate(r,-240,'crop');
%             r(~bw) = 0.47;
%             g(~bw) = 0.47;
%             b(~bw) = 0.47;
%             rgb = cat(3,r+g,g+b,r+b);
        end
        
        function fncUpdateResourceOptions(app)
            app.ResourceDropDown_1.Items = app.ResourceTable.Data(:,1);
            app.ResourceDropDown_2.Items = app.ResourceTable.Data(:,1);
            app.ResourceDropDown_3.Items = app.ResourceTable.Data(:,1);
            app.ResourceDropDown_4.Items = app.ResourceTable.Data(:,1);
        end
        
        function fncUpdateResourceCNP(app)
            for iR = 1:4
                resource = app.(['ResourceDropDown_' num2str(iR)]).Value;
                row = strcmp(resource,app.ResourceTable.Data(:,1));
                C_to_N = str2double(app.ResourceTable.Data{row,2});
                N_to_P = str2double(app.ResourceTable.Data{row,3});
                tau = str2double(app.ResourceTable.Data{row,4});
                CNP = [num2str(C_to_N*N_to_P) ':' num2str(N_to_P) ':' num2str(1)];
                app.(['ResourceLabel_' num2str(iR)]).Text = CNP;
                app.(['TauEditField_' num2str(iR)]).Value = tau;
            end
        end
        
        function fncUpdateInternalCNP(app)
            Cm = app.Cin.Value/12;
            Nm = app.Nin.Value/14;
            Pm = app.Pin.Value/31;
            app.CNPiRatio.Text = [num2str(Cm/Pm,'%0.0f') ':' num2str(Nm/Pm, '%0.0f') ':' num2str(1)];
            % the total Cin required includes respiratory carbon
            app.CinTotal.Value = app.Cin.Value/app.CUE.Value;
        end
        
        function [Mu_cell, Mu_immobile, Mu_motile, Mu_autolytic, Mu_fungal, Recalcitrance, C_to_N_vector] = fnc_vary_CNP_and_tau(app, N_to_P, kappa)
            
            C_to_N_min = app.CNMin.Value;
            C_to_N_max = app.CNMax.Value;
            tau_min = app.TauMin.Value;
            tau_max = app.TauMax.Value;
            % res1 is the number of different values for the C:N ratio and
            % recalcitrance that are tried
            res1 = app.Res1.Value;
            % Ci is the mass of carbon needed per unit volume of growth, in g per ml
            Ci = app.CinTotal.Value;
            Ni = app.Nin.Value;
            Pi = app.Pin.Value;
            % dry weight in grams per ml of the substrate
            density = app.Density.Value;
            % epsilon is the efficiency of recycling for senscent cells
            epsilon = app.Epsilon.Value;
            % alpha is the mass of machinery needed for cell mobility, relative
            % to the mass of essential machinery
            alpha = app.Alpha.Value;
            % beta is the mass of material in vesicles, relative to the mass of
            % the rest of the fungus, including hydrolases
            beta = app.Beta.Value;
            % lambda is the maximum rate of resource use per unit volume,
            % in g per ml per hour
            lambda = app.Lambda.Value;
            delta = app.Delta.Value;
            
            % xres determines the number of different values for x that are tried in
            % finding the optimal solution. Final value found is accurate to
            % xres^2
            xres = 200;
            
            % sres is the number of iterations used in finding best solution for
            % a given environment and a given rate of sythesis
            sres = 60;
            
            % L is the maximum rate of resource use per unit volume,
            % relative to Ci + Ni + Pi
            L = lambda/(Ci + Ni + Pi);
            
            C_to_N_vector = zeros(res1, 1);
            Recalcitrance = zeros(res1, 1);
            
            Mu_immobile = zeros(res1);
            Mu_motile = zeros(res1);
            Mu_autolytic = zeros(res1);
            Mu_fungal = zeros(res1);
            Mu_cell = zeros(res1);
            
            x_immobile = zeros(res1);
            x_motile = zeros(res1);
            x_autolytic = zeros(res1);
            x_fungal = zeros(res1);
            xC_fungal = zeros(res1);
            xN_fungal = zeros(res1);
            xP_fungal = zeros(res1);
            x_cell = zeros(res1);
            
            for i = 1:res1
                
                C_to_N = exp(log(C_to_N_min) + ...
                    (log(C_to_N_max) - log(C_to_N_min))*(i-1)/(res1-1));
                
                C_to_N_vector(i) = C_to_N;
                
                M_tot = 12*C_to_N*N_to_P + 14*N_to_P + 31;
                
                Ce = density*12*C_to_N*N_to_P/M_tot;
                Ne = density*14*N_to_P/M_tot;
                Pe = density*31/M_tot;
                
                for j = 1:res1
                    
                    tau = exp(log(tau_min) + ...
                        (log(tau_max) - log(tau_min))*(j-1)/(res1-1));
                    
                    Recalcitrance(j) = tau;
                    
                    [Mu_cell(i,j), x_cell(i,j)] = find_best_cell ...
                        (Ci, Ni, Pi, Ce, Ne, tau, delta, L);
                    
                    [Mu_immobile(i,j), x_immobile(i,j)] = find_best_immobile ...
                        (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, sres, xres);
                    
                    [Mu_motile(i,j), x_motile(i,j)] = find_best_motile ...
                        (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, alpha, sres, xres);
                    
                    [Mu_autolytic(i,j), ~, x_autolytic(i,j)] = find_best_autolytic ...
                        (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, epsilon, sres, xres);
                    
                    [Mu_fungal(i,j), x_fungal(i,j), xC_fungal(i,j), ...
                        xN_fungal(i,j), xP_fungal(i,j)] = find_best_fungi( ...
                        Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, beta, sres, 2*xres);
                end
            end
            
            
        end
        
        function [Mu_immobile, Mu_motile, Mu_autolytic, Mu_fungal, D_immobile, D_motile, D_autolytic, D_fungal,DC_fungal,DN_fungal,DP_fungal, kappa_vector] = fnc_vary_kappa(app,N_to_P, k_min, k_max, C_to_N, tau)
            % k_min and k_max are the minimum and maximum values for the resource
            % acquisition length that we try
            % tau is the time in hours for hydrolases to digest their own mass
            
            % res1 is the number of different values for kappa that we try
            res1 = app.Res1.Value;
            % xres is the number of iterations used in finding best solution for
            % a given environment and a given rate of sythesis
            xres = 200;
            % sres is the number of iterations used in finding best solution for
            % a given environment and a given rate of sythesis
            sres = 60;

            % Ci is the mass of carbon needed per unit volume of growth, in g per ml
            Ci = app.CinTotal.Value;
            Ni = app.Nin.Value;
            Pi = app.Pin.Value;
            % dry weight in grams per ml of the substrate
            density = app.Density.Value;
            % epsilon is the efficiency of recycling for autolytic cells
            epsilon = app.Epsilon.Value;
            % alpha is the mass of machinery needed for cell mobility, relative
            % to the mass of essential machinery
            alpha = app.Alpha.Value;
            % beta is the mass of material in vesicles, relative to the mass of
            % the rest of the fungus, including hydrolases
            beta = app.Beta.Value;
            % lambda is the maximum rate of resource use per unit volume,
            % in g per ml per hour
            lambda = app.Lambda.Value;
            % delta is the minimal fraction of C that must be digested in order to
            % digest the available N
            delta = app.Delta.Value;
            
            C_to_P = C_to_N*N_to_P;
            
            M_tot = 12*C_to_P + 14*N_to_P + 31;
            Ce = density*12*C_to_P/M_tot;
            Ne = density*14*N_to_P/M_tot;
            Pe = density*31/M_tot;
            
            % L is the maximum rate of resource use per unit volume,
            % relative to Ci + Ni + Pi
            L = lambda/(Ci + Ni + Pi);
            
            % phi is the correction term
            phi = (Ci + Ni)/(Ci + Ni + Pi);
            
            kappa_vector = zeros(res1, 1);
            
            % apparent growth rates for each category of organism
            Mu_immobile = zeros(res1, 1);
            Mu_motile = zeros(res1, 1);
            Mu_autolytic = zeros(res1, 1);
            Mu_fungal = zeros(res1, 1);
            
            % rate of digestion per unit volume for each category of organism
            D_immobile = zeros(res1, 1);
            D_motile = zeros(res1, 1);
            D_autolytic = zeros(res1, 1);
            D_fungal = zeros(res1, 1);
            DC_fungal = zeros(res1, 1);
            DP_fungal = zeros(res1, 1);
            DN_fungal = zeros(res1, 1);
            
            for i = 1:res1
                
                kappa = k_min + (k_max - k_min)*(i-1)/(res1-1);
                kappa_vector(i) = kappa;
                
                [M, x] = find_best_immobile...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, sres, xres);
                
                Mu_immobile(i) = M;
                D_immobile(i) = phi*x/tau;
                
                [M, x] = find_best_motile ...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, alpha, sres, xres);
                
                Mu_motile(i) = M;
                D_motile(i) = phi*x/tau;
                
                [M, ~, x] = find_best_autolytic ...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, epsilon, sres, xres);
                
                Mu_autolytic(i) = M;
                D_autolytic(i) = phi*x/tau;
                
                [M, x, xC, xN, xP] = find_best_fungi...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, beta, sres, 3*xres);
                
                Mu_fungal(i) = M;
                DC_fungal(i) = phi*xC/tau;
                DN_fungal(i) = phi*xN/tau;
                DP_fungal(i) = phi*xP/tau;
                D_fungal(i) = phi*x/tau;
            end
        end
        
        function [CYM] = fnc_generate_CYM_merge(app, Mu_immobile, Mu_motile, Mu_autolytic, Mu_fungal)
            res1 = length(Mu_fungal);
            MG = ones(res1, res1, 3);
            CYM = ones(res1, res1, 3);
            
            % if the fastest growing colony has grown by a factor of
            % of n, and a slower growing colony has grown by a factor
            % of m, the channels for the colonies are coloured according
            % with intensity 1 and m/n respectively. Higher n increases the
            % apparent diffence in colour, making a lighter CYM merge.
            n = 1000;
            
            for i = 1:res1
                for j = 1:res1
                    
                    max_g =  max([Mu_motile(i,j), Mu_fungal(i,j), Mu_autolytic(i,j)]);
                    
                    max_f_or_i = max([Mu_immobile(i,j), Mu_fungal(i,j)]);
                    
                    if max_g > 0
                        
                        CYM(i,j,2) = ...
                            1 - (1/n)*exp(Mu_fungal(i,j)*log(n)/max_g);
                        
                        CYM(i,j,3) = ...
                            1 - (1/n)*exp(Mu_autolytic(i,j)*log(n)/max_g);
                        
                        CYM(i,j,1) = ...
                            1 - (1/n)*exp(Mu_motile(i,j)*log(n)/max_g);
                    end
                    
                    if max_f_or_i > 0
                        
                        MG(i,j,1) = ...
                            1 - (1/n)*exp(Mu_immobile(i,j)*log(n)/max_f_or_i);
                        
                        MG(i,j,3) = MG(i,j,1);
                        
                        MG(i,j,2) = ...
                            1 - (1/n)*exp(Mu_fungal(i,j)*log(n)/max_f_or_i);
                    end
                end
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set the default values
            app.NPMin.Value = 5;
            app.NPMid.Value = 20;
            app.NPMax.Value = 40;
            app.KappaMin.Value = 2;
            app.KappaMid.Value = 4;
            app.KappaMax.Value = 6;
            app.CNMin.Value = 5;
            app.CNMax.Value = 300;
            app.TauMin.Value = 0.05;
            app.TauMax.Value = 50;
            app.Res1.Value = 200;
            app.Density.Value = 0.5;
            app.Alpha.Value = 0.02;
            app.Beta.Value = 0.1;
            app.Delta.Value = 0;
            app.Epsilon.Value = 0.9;
            app.Cin.Value = 0.16;
            app.CUE.Value = 0.5;
            app.Nin.Value = 0.032;
            app.Pin.Value = 0.005;
            app.Lambda.Value = 0.3;
            % calculate internal C:N:P ratio and display
            fncUpdateInternalCNP(app)
            % turn off autoresize to allow subplots
            app.PanelResults.AutoResizeChildren = 'off';
            % set up the controls
            app.DisplayDropDown.Value = 'CYM';
            app.NPratioDropDown.Value = 'min';
            app.NPratioDropDown.Enable = 'off';
            app.NPratioDropDownLabel.Enable = 'off';
            app.OrganismDropDown.Enable = 'off';
            app.OrganismDropDownLabel.Enable = 'off';
            app.Image.ImageSource = ColourLegend(app);
            % set up some default resources
            resourceType = { ...
                'wood'; ...
                'litter'; ...
                'dung'; ...
                'agar'};
            resourceValue = sprintfc('%0.1f', [ ...
                200,20,40; ...
                62.5,20,20; ...
                20,5,5; ...
                20,5,0.1]);
            app.ResourceTable.Data = [resourceType, resourceValue];
            app.ResourceDropDown_1.Value = resourceType{1,1};
            app.ResourceDropDown_2.Value = resourceType{2,1};
            app.ResourceDropDown_3.Value = resourceType{3,1};
            app.ResourceDropDown_4.Value = resourceType{4,1};
            % update the resource dropdown menus
            fncUpdateResourceOptions(app)
            fncUpdateResourceCNP(app)
            % set the background to white
            app.PanelResults.BackgroundColor = [1 1 1];
            app.PanelResults_2.BackgroundColor = [1 1 1];
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            tic
            N_to_P_limits = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappa_limits = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            dlg = uiprogressdlg(app.T2M,'Title','Calculating performance for each organism',...
                'Message','','Cancelable',"on");
            n = 0;
            for iKappa = 1:3
                if dlg.CancelRequested
                    break
                end
                for iN2P = 1:3
                    n = n+1;
                    if dlg.CancelRequested
                        break
                    end
                    N_to_P = N_to_P_limits(iN2P);
                    kappa = kappa_limits(iKappa);
                    dlg.Value = n/10;
                    dlg.Message = ['Performance at N:P ratio of ' num2str(N_to_P,2) ' and digestion radius of ' num2str(kappa,2)];
                    [app.Mu.cell{iKappa,iN2P}, ...
                        app.Mu.immobile{iKappa,iN2P}, ...
                        app.Mu.motile{iKappa,iN2P}, ...
                        app.Mu.autolytic{iKappa,iN2P}, ...
                        app.Mu.fungal{iKappa,iN2P}, ...
                        app.Recalcitrance, ...
                        app.C_to_N_vector] = fnc_vary_CNP_and_tau(app, N_to_P, kappa);
                end
            end
            fncUpdateDisplayCYM(app)
            close(dlg)
            toc
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            switch app.DisplayDropDown.Value
                case 'CYM'
                    fncUpdateDisplayCYM(app)
                    fncUpdateDisplayCYM(app)
                case 'Growth'
                    fncUpdateDisplayGrowth(app)
                    fncUpdateDisplayGrowth(app)
                case 'Ratio'
                    fncUpdateDisplayRatio(app)
                    fncUpdateDisplayRatio(app)
            end
            
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            switch app.DisplayDropDown.Value
                case 'CYM'
                    app.NPratioDropDown.Enable = 'off';
                    app.NPratioDropDownLabel.Enable = 'off';
                    app.OrganismDropDown.Enable = 'off';
                    app.OrganismDropDownLabel.Enable = 'off';
                    app.Image.Visible = 'on';
                    app.autolyticLabel.Visible = 'on';
                    app.motileLabel.Visible = 'on';
                    app.fungalLabel.Visible = 'on';
                case 'Growth'
                    app.NPratioDropDown.Enable = 'on';
                    app.NPratioDropDownLabel.Enable = 'on';
                    app.OrganismDropDown.Enable = 'off';
                    app.OrganismDropDownLabel.Enable = 'off';
                    app.Image.Visible = 'off';
                    app.autolyticLabel.Visible = 'off';
                    app.motileLabel.Visible = 'off';
                    app.fungalLabel.Visible = 'off';
                case 'Ratio'
                    app.NPratioDropDown.Enable = 'off';
                    app.NPratioDropDownLabel.Enable = 'off';
                    app.OrganismDropDown.Enable = 'on';
                    app.OrganismDropDownLabel.Enable = 'on';
                    app.Image.Visible = 'off';
                    app.autolyticLabel.Visible = 'off';
                    app.motileLabel.Visible = 'off';
                    app.fungalLabel.Visible = 'off';
            end
        end

        % Button pushed function: RunResourceButton
        function RunResourceButtonPushed(app, event)
            dlg = uiprogressdlg(app.T2M,'Title','Calculating optimal investment for each resource environment. Please wait...',...
                'Message','','Cancelable',"on");
            n = 0;
            for iR = 1:4
                if dlg.CancelRequested
                    break
                end
                n = n+1;
                resource = app.(['ResourceDropDown_' num2str(iR)]).Value;
                dlg.Message = ['Optimal allocation for ' resource];
                dlg.Value = n/5;
                row = strcmp(resource,app.ResourceTable.Data(:,1));
                C_to_N = str2double(app.ResourceTable.Data{row,2});
                N_to_P = str2double(app.ResourceTable.Data{row,3});
                %                 tau = app.ResourceTable.Data{row,4};
                tau = app.(['TauEditField_' num2str(iR)]).Value;
                [app.MuPlot.immobile{iR}, app.MuPlot.motile{iR}, app.MuPlot.autolytic{iR}, app.MuPlot.fungal{iR}, ...
                    app.DPlot.immobile{iR}, app.DPlot.motile{iR}, app.DPlot.autolytic{iR}, app.DPlot.fungal{iR}, ...
                    app.DPlot.Cfungal{iR},app.DPlot.Nfungal{iR},app.DPlot.Pfungal{iR}, app.kappa_vector] = fnc_vary_kappa(app, ...
                    N_to_P, ...
                    1, ...
                    8, ...
                    C_to_N, ...
                    tau);
            end
            fncUpdateMuPlot(app)
        end

        % Button pushed function: ResourceAddButton
        function ResourceAddButtonPushed(app, event)
            oldData = app.ResourceTable.Data;
            if ~contains(oldData(:,1),'blank')
                newRow = [{'blank'} sprintfc('%0.0f',[0,0,0])];
                app.ResourceTable.Data = [oldData; newRow];
            end
        end

        % Button pushed function: ResourceUpdateButton
        function ResourceUpdateButtonPushed(app, event)
            fncUpdateResourceOptions(app)
            fncUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_1
        function ResourceDropDown_1ValueChanged(app, event)
            fncUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_2
        function ResourceDropDown_2ValueChanged(app, event)
            fncUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_3
        function ResourceDropDown_3ValueChanged(app, event)
            fncUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_4
        function ResourceDropDown_4ValueChanged(app, event)
            fncUpdateResourceCNP(app)
        end

        % Button pushed function: ResourceSaveButton
        function ResourceSaveButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Save the resource table','Resource.mat');
            data = app.ResourceTable.Data;
            save(fullfile(path,file),"data")
        end

        % Button pushed function: ResourceLoadButton
        function ResourceLoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Load a saved resource table','Resource.mat');
            if ~isempty(file)
                temp = load(fullfile(path,file));
                if isfield(temp,'data') && ~isempty(temp.data)
                    app.ResourceTable.Data = temp.data;
                    fncUpdateResourceOptions(app)
                    fncUpdateResourceCNP(app)
                end
            end
        end

        % Value changed function: CinTotal
        function CinTotalValueChanged(app, event)
            fncUpdateInternalCNP(app)
        end

        % Value changed function: Nin
        function NinValueChanged(app, event)
            fncUpdateInternalCNP(app)
        end

        % Value changed function: Pin
        function PinValueChanged(app, event)
            fncUpdateInternalCNP(app)
        end

        % Button pushed function: PrintModelButton
        function PrintModelButtonPushed(app, event)
            [file,path] = uiputfile('*.png','Save model output','ModelOutput');
            [~,fname,~] = fileparts(file);
            fout = [fname '_' app.DisplayDropDown.Value];                
            % crop amounts are given as [top,right,bottom,left]
            export_fig(fullfile(path,fout),'-png','-pdf','-c[10 560 10 301]',app.T2M);
        end

        % Value changed function: Cin
        function CinValueChanged(app, event)
            fncUpdateInternalCNP(app)
        end

        % Value changed function: CUE
        function CUEValueChanged(app, event)
            fncUpdateInternalCNP(app)
        end

        % Button pushed function: PrintResourcesButton
        function PrintResourcesButtonPushed(app, event)
            [file,path] = uiputfile('*.png','Save resource plots','ResourcePlots');
            % crop amounts are given as [top,right,bottom,left]
            export_fig(fullfile(path,file),'-png','-pdf','-c[10 10 10 1051]',app.T2M);
        end

        % Button pushed function: ResourceRemoveButton
        function ResourceRemoveButtonPushed(app, event)
            row = app.ResourceTableIdx(1);
            data = app.ResourceTable.Data;
            data(row,:) = [];
            app.ResourceTable.Data = data;
        end

        % Cell selection callback: ResourceTable
        function ResourceTableCellSelection(app, event)
            indices = event.Indices;
            app.ResourceTableIdx = indices;
        end

        % Button pushed function: ResourcePanelSave
        function ResourcePanelSaveButtonPushed(app, event)
            [file,path] = uiputfile('*.png','Save model output','ResourcePanel');
            if ~isempty(file)
                [~,fname,~] = fileparts(file);
                screencapture(0,[200 -900 600 1800],'target',fullfile(path,[fname '.png']))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.T2M = uifigure('Visible', 'off');
            app.T2M.Name = 'UI Figure';
            app.PanelResults = uipanel(app.T2M);
            app.PanelResults_2 = uipanel(app.T2M);
            app.TabGroup = uitabgroup(app.T2M);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.PanelEnvironment = uipanel(app.SettingsTab);
            app.PanelEnvironment.Title = 'Environmental parameters';
            app.minLabel = uilabel(app.PanelEnvironment);
            app.minLabel.Text = 'min';
            app.CNMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.maxLabel = uilabel(app.PanelEnvironment);
            app.maxLabel.Text = 'max';
            app.CNMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.TauMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.TauMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.minLabel_2 = uilabel(app.PanelEnvironment);
            app.minLabel_2.Text = 'min';
            app.NPMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.midLabel = uilabel(app.PanelEnvironment);
            app.midLabel.Text = 'mid';
            app.NPMid = uieditfield(app.PanelEnvironment, 'numeric');
            app.maxLabel_2 = uilabel(app.PanelEnvironment);
            app.maxLabel_2.Text = 'max';
            app.NPMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.KappaMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.KappaMid = uieditfield(app.PanelEnvironment, 'numeric');
            app.KappaMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.DeltaLabel = uilabel(app.PanelEnvironment);
            app.DeltaLabel.Text = 'Delta';
            app.Delta = uieditfield(app.PanelEnvironment, 'numeric');
            app.NolevelsLabel = uilabel(app.PanelEnvironment);
            app.NolevelsLabel.Text = 'No. levels';
            app.Res1 = uieditfield(app.PanelEnvironment, 'numeric');
            app.CNLabel = uilabel(app.PanelEnvironment);
            app.CNLabel.Text = 'C:N';
            app.TauhLabel = uilabel(app.PanelEnvironment);
            app.TauhLabel.Text = 'Tau (h)';
            app.NPLabel = uilabel(app.PanelEnvironment);
            app.NPLabel.Text = 'N:P';
            app.KappaLabel = uilabel(app.PanelEnvironment);
            app.KappaLabel.Text = 'Kappa';
            app.DensityEditFieldLabel = uilabel(app.PanelEnvironment);
            app.DensityEditFieldLabel.Text = 'Density';
            app.Density = uieditfield(app.PanelEnvironment, 'numeric');
            app.Density.Limits = [0 1];
            app.PanelOrganism = uipanel(app.SettingsTab);
            app.PanelOrganism.Title = 'Organism parameters';
            app.CitotgmlLabel = uilabel(app.PanelOrganism);
            app.CitotgmlLabel.Text = 'Ci tot (g/ml)';
            app.CinTotal = uieditfield(app.PanelOrganism, 'numeric');
            app.CinTotal.Limits = [0 Inf];
            app.CinTotal.ValueChangedFcn = createCallbackFcn(app, @CinTotalValueChanged, true);
            app.NigmlLabel = uilabel(app.PanelOrganism);
            app.NigmlLabel.Text = 'Ni (g/ml)';
            app.Nin = uieditfield(app.PanelOrganism, 'numeric');
            app.Nin.Limits = [0 1];
            app.Nin.ValueChangedFcn = createCallbackFcn(app, @NinValueChanged, true);
            app.PigmlLabel = uilabel(app.PanelOrganism);
            app.PigmlLabel.Text = 'Pi (g/ml)';
            app.Pin = uieditfield(app.PanelOrganism, 'numeric');
            app.Pin.Limits = [0 1];
            app.Pin.ValueChangedFcn = createCallbackFcn(app, @PinValueChanged, true);
            app.AlphaLabel = uilabel(app.PanelOrganism);
            app.AlphaLabel.Text = 'Alpha';
            app.Alpha = uieditfield(app.PanelOrganism, 'numeric');
            app.BetaLabel = uilabel(app.PanelOrganism);
            app.BetaLabel.Text = 'Beta';
            app.Beta = uieditfield(app.PanelOrganism, 'numeric');
            app.EpsilonLabel = uilabel(app.PanelOrganism);
            app.EpsilonLabel.Text = 'Epsilon';
            app.Epsilon = uieditfield(app.PanelOrganism, 'numeric');
            app.LambdaLabel = uilabel(app.PanelOrganism);
            app.LambdaLabel.Text = 'Lambda';
            app.Lambda = uieditfield(app.PanelOrganism, 'numeric');
            app.RunModelButton = uibutton(app.PanelOrganism, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.Image = uiimage(app.PanelOrganism);
            app.fungalLabel = uilabel(app.PanelOrganism);
            app.fungalLabel.Text = 'fungal';
            app.motileLabel = uilabel(app.PanelOrganism);
            app.motileLabel.Text = 'motile';
            app.autolyticLabel = uilabel(app.PanelOrganism);
            app.autolyticLabel.Text = 'autolytic';
            app.CNPiRatio = uilabel(app.PanelOrganism);
            app.CNPiLabel = uilabel(app.PanelOrganism);
            app.CNPiLabel.Text = 'Ci:Ni:Pi';
            app.CiLabel = uilabel(app.PanelOrganism);
            app.CiLabel.Text = 'Ci';
            app.Cin = uieditfield(app.PanelOrganism, 'numeric');
            app.Cin.Limits = [0 Inf];
            app.Cin.ValueChangedFcn = createCallbackFcn(app, @CinValueChanged, true);
            app.CUELabel_2 = uilabel(app.PanelOrganism);
            app.CUELabel_2.Text = 'CUE';
            app.CUE = uieditfield(app.PanelOrganism, 'numeric');
            app.CUE.Limits = [0 Inf];
            app.CUE.ValueChangedFcn = createCallbackFcn(app, @CUEValueChanged, true);
            app.StrategycostsLabel = uilabel(app.PanelOrganism);
            app.StrategycostsLabel.Text = 'Strategy costs';
            app.DisplayPanel = uipanel(app.SettingsTab);
            app.DisplayPanel.Title = 'Display';
            app.DisplayLabel = uilabel(app.DisplayPanel);
            app.DisplayLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.DisplayPanel);
            app.DisplayDropDown.Items = {'CYM', 'Growth', 'Ratio'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'CYM';
            app.NPratioDropDownLabel = uilabel(app.DisplayPanel);
            app.NPratioDropDownLabel.Text = 'N:P ratio';
            app.NPratioDropDown = uidropdown(app.DisplayPanel);
            app.NPratioDropDown.Items = {'min', 'mid', 'max'};
            app.NPratioDropDown.Value = 'min';
            app.OrganismDropDownLabel = uilabel(app.DisplayPanel);
            app.OrganismDropDownLabel.Text = 'Organism';
            app.OrganismDropDown = uidropdown(app.DisplayPanel);
            app.OrganismDropDown.Items = {'immobile', 'autolytic', 'motile'};
            app.OrganismDropDown.Value = 'autolytic';
            app.UpdateButton = uibutton(app.DisplayPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PrintModelButton = uibutton(app.DisplayPanel, 'push');
            app.PrintModelButton.ButtonPushedFcn = createCallbackFcn(app, @PrintModelButtonPushed, true);
            app.PrintModelButton.Text = 'Print';
            app.SpecificResourcesPanel = uipanel(app.SettingsTab);
            app.SpecificResourcesPanel.Title = 'Specific Resources';
            app.Resource1DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource1DropDownLabel.Text = 'Resource 1';
            app.ResourceDropDown_1 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_1.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_1ValueChanged, true);
            app.ResourceDropDown_1.Value = 'wood';
            app.Resource2DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource2DropDownLabel.Text = 'Resource 2';
            app.ResourceDropDown_2 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_2.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_2ValueChanged, true);
            app.ResourceDropDown_2.Value = 'litter';
            app.Resource4DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource4DropDownLabel.Text = 'Resource 4';
            app.ResourceDropDown_4 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_4.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_4ValueChanged, true);
            app.ResourceDropDown_4.Value = 'agar';
            app.ResourceDropDown_3 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_3.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_3ValueChanged, true);
            app.ResourceDropDown_3.Value = 'dung';
            app.Resource3DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource3DropDownLabel.Text = 'Resource 3';
            app.RunResourceButton = uibutton(app.SpecificResourcesPanel, 'push');
            app.RunResourceButton.ButtonPushedFcn = createCallbackFcn(app, @RunResourceButtonPushed, true);
            app.RunResourceButton.Text = 'Run';
            app.ResourceLabel_1 = uilabel(app.SpecificResourcesPanel);
            app.TauhEditFieldLabel = uilabel(app.SpecificResourcesPanel);
            app.TauhEditFieldLabel.Text = 'Tau (h)';
            app.TauEditField_1 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.TauEditField_1.Limits = [0 Inf];
            app.ResourceLabel_2 = uilabel(app.SpecificResourcesPanel);
            app.TauEditField_2 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.TauEditField_2.Limits = [0 Inf];
            app.ResourceLabel_3 = uilabel(app.SpecificResourcesPanel);
            app.TauEditField_3 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.TauEditField_3.Limits = [0 Inf];
            app.ResourceLabel_4 = uilabel(app.SpecificResourcesPanel);
            app.TauEditField_4 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.TauEditField_4.Limits = [0 Inf];
            app.ResourceLabel_CNP = uilabel(app.SpecificResourcesPanel);
            app.ResourceLabel_CNP.Text = 'Ce:Ne:Pe';
            app.PrintResourcesButton = uibutton(app.SpecificResourcesPanel, 'push');
            app.PrintResourcesButton.ButtonPushedFcn = createCallbackFcn(app, @PrintResourcesButtonPushed, true);
            app.PrintResourcesButton.Text = 'Print';
            app.ResourcesTab = uitab(app.TabGroup);
            app.ResourcesTab.Title = 'Resources';
            app.ResourceTable = uitable(app.ResourcesTab);
            app.ResourceTable.ColumnName = {'Resource'; 'C:N'; 'N:P'; 'Tau'};
            app.ResourceTable.RowName = {};
            app.ResourceTable.ColumnEditable = true;
            app.ResourceTable.CellSelectionCallback = createCallbackFcn(app, @ResourceTableCellSelection, true);
            app.ResourceAddButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceAddButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceAddButtonPushed, true);
            app.ResourceAddButton.Text = 'Add';
            app.ResourceRemoveButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceRemoveButtonPushed, true);
            app.ResourceRemoveButton.Text = 'Remove';
            app.ResourceUpdateButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceUpdateButtonPushed, true);
            app.ResourceUpdateButton.Text = 'Update';
            app.ResourceLoadButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceLoadButtonPushed, true);
            app.ResourceLoadButton.Text = 'Load';
            app.ResourceSaveButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceSaveButtonPushed, true);
            app.ResourceSaveButton.Text = 'Save';
            app.ResourcePanelSave = uibutton(app.ResourcesTab, 'push');
            app.ResourcePanelSave.ButtonPushedFcn = createCallbackFcn(app, @ResourcePanelSaveButtonPushed, true);
            app.ResourcePanelSave.Text = 'Save Panel';
            % Show the figure after all components are created
            app.T2M.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fungal_T2M_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.T2M)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T2M)
        end
    end
end